classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClickImageButton            matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        ExposureModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown        matlab.ui.control.DropDown
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        FrameRateModeDropDownLabel  matlab.ui.control.Label
        FrameRateModeDropDown       matlab.ui.control.DropDown
        ShutterModeDropDownLabel    matlab.ui.control.Label
        ShutterModeDropDown         matlab.ui.control.DropDown
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Video = ba_config_video('Grasshopper3');
        vid;
        videoInputNumber = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mkdir 'SessionData'
            %app.vid = videoinput('winvideo', app.videoInputNumber);
            app.vid = videoinput('pointgrey', 2, 'F7_Raw16_1024x768_Mode2');
            
            % m = load([INSERT FILEPATH TO EXISTING PROPERTIES HERE!]);
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.FrameRateModeDropDown.Value = app.Video.FrameRateMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
            app.ShutterModeDropDown.Value = app.Video.ShutterMode;
            app.ExposureTimeEditField.Value = app.Video.ExposureTime;
            
            src = getselectedsource(app.vid); 
            src.ExposureMode = app.Video.ExposureMode;
            src.FrameRateMode = app.Video.FrameRateMode;
            src.ShutterMode = app.Video.ShutterMode;
            src.Gain = app.Video.Gain;
            src.Gamma = app.Video.Gamma;
            src.Brightness = app.Video.Brightness;
            src.Shutter = app.Video.ExposureTime;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
            
            pImage = imshow(uint16(zeros(imageRes)));
            
            axis image
            setappdata(pImage, 'UpdatePreviewWindowFcn', @ba_pulloffview)
            p = preview(app.vid, pImage);
            set(p, 'CDataMapping', 'scaled');
            
        end

        % Button pushed function: ClickImageButton
        function ClickImageButtonPushed(app, event)
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            close all
            
            src.ExposureMode = app.ExposureModeDropDown.Value;
            src.FrameRateMode = app.FrameRateModeDropDown.Value;
            src.ShutterMode = app.ShutterModeDropDown.Value;
            src.Gain = app.GammaEditField.Value;
            src.Gamma = app.GammaEditField.Value;
            src.Brightness = app.BrightnessEditField.Value;
            src.ExposureTime = app.ExposureTimeEditField.Value;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
            
            pImage = imshow(uint16(zeros(imageRes)));
            
            axis image
            setappdata(pImage, 'UpdatePreviewWindowFcn', @ba_pulloffview)
            p = preview(app.vid, pImage);
            set(p, 'CDataMapping', 'scaled');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClickImageButton = uibutton(app.UIFigure, 'push');
            app.ClickImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClickImageButtonPushed, true);
            app.ClickImageButton.Text = 'Click Image';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ExposureModeDropDownLabel = uilabel(app.UIFigure);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.UIFigure);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateModeDropDownLabel = uilabel(app.UIFigure);
            app.FrameRateModeDropDownLabel.Text = 'Frame Rate Mode';
            app.FrameRateModeDropDown = uidropdown(app.UIFigure);
            app.FrameRateModeDropDown.Items = {'on', 'off'};
            app.FrameRateModeDropDown.Value = 'on';
            app.ShutterModeDropDownLabel = uilabel(app.UIFigure);
            app.ShutterModeDropDownLabel.Text = 'Shutter Mode';
            app.ShutterModeDropDown = uidropdown(app.UIFigure);
            app.ShutterModeDropDown.Items = {'manual'};
            app.ShutterModeDropDown.Value = 'manual';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end